classdef Apka_Transformatka < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApkaTransformatkaUIFigure     matlab.ui.Figure
        LoadimageButton               matlab.ui.control.Button
        DrawspirallyButton            matlab.ui.control.Button
        DrawverticallyButton          matlab.ui.control.Button
        LoaddatapointsButton          matlab.ui.control.Button
        TransformmethodDropDownLabel  matlab.ui.control.Label
        TransformmethodDropDown       matlab.ui.control.DropDown
        DrawalongthecontourButton     matlab.ui.control.Button
        LatencymsSliderLabel          matlab.ui.control.Label
        LatencymsSlider               matlab.ui.control.Slider
        OffsetEditFieldLabel          matlab.ui.control.Label
        OffsetEditField               matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        NajpierwwgrajzdjecieLabel     matlab.ui.control.Label
        DrawButton                    matlab.ui.control.Button
        ConenctpointsCheckBox         matlab.ui.control.CheckBox
        ConnectpointsCheckBox         matlab.ui.control.CheckBox
        MakegifCheckBox               matlab.ui.control.CheckBox
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Created by Mateusz Górczany & Karol Szeliga

        nazwa_zdjecia = 'x';
        path = 'x';
        waiter = 0;
        
        cords = 0;
        drawingDane =0;
        radiiDane=0;
        setupDane=0;
        
        cords_lin = 0; 
        
        cords_pion = 0; 
        
        cords_spir = 0; 
        
        sposob = 1;
        
        drawingP =0;
        radiiP=0;
        setupP=0;
        
        drawingL =0;
        radiiL=0;
        setupL=0;
        
        drawingS =0;
        radiiS=0;
        setupS=0;
    end
    
    methods (Access = private)


end %kończy wszystkie funkcje


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            cla(app.UIAxes)
            %%
            app.drawingP =0;
            app.radiiP=0;
            app.setupP=0;
        
            app.drawingL =0;
            app.radiiL=0;
            app.setupL=0;
        
            app.drawingS =0;
            app.radiiS=0;
            app.setupS=0;
            %%
%             if app.cords_lin ~= 0 | app.cords_pion ~= 0 | app.cords_spir ~= 0
%                 app.cords_lin = 0;
%                 %app.round_lin = 0;
%                 app.cords_pion = 0; 
%                 app.round_pion = 0;
%                 app.cords_spir = 0; 
%                 app.round_spir = 0; 
%               
%            end
            app.NajpierwwgrajzdjecieLabel.Visible = 'off';
            [app.nazwa_zdjecia,app.path] = uigetfile({'*.jpg';'*.jpeg';'*.png'}, 'Wybierz zdjęcie'); % zapisuje nazwę pliku, który wybierzesz z dialog boxa      
        end

        % Button pushed function: DrawspirallyButton
        function DrawspirallyButtonPushed(app, event)
            if app.cords_spir == 0 
                uialert(app.ApkaTransformatkaUIFigure, 'Nie policzono transformaty tym sposobem', 'Błąd', 'Icon', 'warning');
            else
               switch app.ConnectpointsCheckBox.Value
                case 1
                    Animateline(app.drawingS,app.radiiS,app.setupS,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                case 0
                    if app.MakegifCheckBox.Value == 0
                        Animate(app.drawingS,app.radiiS,app.setupS,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                    else
                        MakeGif(app.drawingS,app.radiiS,app.setupS,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                    end
                otherwise 
                    disp("Błąd");
               end
            end
        end

        % Value changed function: TransformmethodDropDown
        function TransformmethodDropDownValueChanged(app, event)
            value = app.TransformmethodDropDown.Value;
            if value(1) == "a"
                app.sposob = 1;
            else
                if value(1) == "v"
                    app.sposob = 2;
                else
                    app.sposob = 3;
                end
            end
                
        end

        % Button pushed function: DrawalongthecontourButton
        function DrawalongthecontourButtonPushed(app, event)
            if app.cords_lin == 0 
                uialert(app.ApkaTransformatkaUIFigure, 'Nie policzono transformaty tym sposobem', 'Błąd', 'Icon', 'warning');
            else
               switch app.ConnectpointsCheckBox.Value
                case 1
                    Animateline(app.drawingL,app.radiiL,app.setupL,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                case 0
                     if app.MakegifCheckBox.Value == 0
                        Animate(app.drawingL,app.radiiL,app.setupL,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                    else
                        MakeGif(app.drawingL,app.radiiL,app.setupL,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                     end
                otherwise 
                    disp("Błąd");
               end
            end
        end

        % Button pushed function: DrawverticallyButton
        function DrawverticallyButtonPushed(app, event)
            if app.cords_pion == 0 
                uialert(app.ApkaTransformatkaUIFigure, 'Nie policzono transformaty tym sposobem', 'Błąd', 'Icon', 'warning');
            else
               switch app.ConnectpointsCheckBox.Value
                case 1
                    Animateline(app.drawingP,app.radiiP,app.setupP,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                case 0
                    if app.MakegifCheckBox.Value == 0
                        Animate(app.drawingP,app.radiiP,app.setupP,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                    else
                        MakeGif(app.drawingP,app.radiiP,app.setupP,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                    end
                otherwise 
                    disp("Błąd");
               end
            end
        end

        % Value changed function: LatencymsSlider
        function LatencymsSliderValueChanged(app, event)
            %value = app.LatencymsSlider.Value;
            
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
          %  value = app.OffsetEditField.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cla(app.UIAxes)
            %cla;
            %cla(app.ApkaTransformatkaUIFigure);
            
        
        app.cords = 0;
        app.drawingDane =0;
        app.radiiDane=0;
        app.setupDane=0;
        
        app.cords_lin = 0; 
        
        app.cords_pion = 0; 
        
        app.cords_spir = 0; 
                
        app.drawingP =0;
        app.radiiP=0;
        app.setupP=0;
        
        app.drawingL =0;
        app.radiiL=0;
        app.setupL=0;
        
        app.drawingS =0;
        app.radiiS=0;
        app.setupS=0;
            
        if app.nazwa_zdjecia == 'x'    
           app.NajpierwwgrajzdjecieLabel.Visible = 'on';
        else
            if app.sposob == 1
                app.cords_lin = LadowaniePoLinii(app.nazwa_zdjecia,app.path);
                
                if isequal(app.cords_lin, [0,0])
                    uialert(app.ApkaTransformatkaUIFigure, 'Zbyt dużo pikseli. Wybierz ładowanie od lewej pionowo', 'Błąd');
                    return
                end
                 app.waiter = uiprogressdlg(app.ApkaTransformatkaUIFigure,'Title','Komunikat',...
                     'Message','Trwa obliczanie','Indeterminate','on');
                plot(app.UIAxes,app.cords_lin(:,1),app.cords_lin(:,2),'.');
                [app.drawingL,app.radiiL, app.setupL] = CalculationsFourier(app.cords_lin);
                %plot(app.UIAxes,app.cords_lin(:,1),app.cords_lin(:,2),'.');
            else
                if app.sposob == 2
                    app.waiter = uiprogressdlg(app.ApkaTransformatkaUIFigure,'Title','Komunikat',...
                     'Message','Trwa obliczanie','Indeterminate','on');
                    app.cords_pion = LadowanieProste(app.nazwa_zdjecia,app.path);
                    %plot(app.UIAxes,app.cords_pion(:,1),app.cords_pion(:,2),'.');                    
                    [app.drawingP,app.radiiP, app.setupP] = CalculationsFourier(app.cords_pion);
                    plot(app.UIAxes,app.cords_pion(:,1),app.cords_pion(:,2),'.');
                else
                    app.cords_spir = LadowanieSpiralne(app.nazwa_zdjecia,app.path);
                    %plot(app.UIAxes,app.cords_spir(:,1),app.cords_spir(:,2),'.');
                    
                    if isequal(app.cords_spir, [0,0])
                        uialert(app.ApkaTransformatkaUIFigure, 'Zbyt dużo pikseli. Wybierz ładowanie od lewej pionowo', 'Błąd');
                        return
                    end
                    app.waiter = uiprogressdlg(app.ApkaTransformatkaUIFigure,'Title','Komunikat',...
                     'Message','Trwa obliczanie','Indeterminate','on');
                    
                    [app.drawingS,app.radiiS, app.setupS] = CalculationsFourier(app.cords_spir);
                    plot(app.UIAxes,app.cords_spir(:,1),app.cords_spir(:,2),'.');
                end
            end
        end
        close(app.waiter);
        end

        % Button pushed function: LoaddatapointsButton
        function LoaddatapointsButtonPushed(app, event)
            %% Kasowanie
            
        app.cords = 0;
        app.drawingDane =0;
        app.radiiDane=0;
        app.setupDane=0;
        
        app.cords_lin = 0; 
        
        app.cords_pion = 0; 
        
        app.cords_spir = 0; 
                
        app.drawingP =0;
        app.radiiP=0;
        app.setupP=0;
        
        app.drawingL =0;
        app.radiiL=0;
        app.setupL=0;
        
        app.drawingS =0;
        app.radiiS=0;
        app.setupS=0;
            
            
            [a,pathy] = uigetfile('*.txt', 'Wybierz dane w formacie txt'); % zapisuje nazwę pliku, który wybierzesz z dialog boxa
            s = importdata(fullfile(pathy,a)); % load data
          
            app.cords = [s(:,1),s(:,2)];

            plot(app.UIAxes,s(:,1),s(:,2),'.');
            [app.drawingDane,app.radiiDane, app.setupDane] = CalculationsFourier(app.cords);

        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            if app.cords == 0 
                uialert(app.ApkaTransformatkaUIFigure, 'Nie wgrano danych', 'Błąd');
                return
            end
            
            if app.ConenctpointsCheckBox.Value == 0
                if app.MakegifCheckBox.Value == 0
                        Animate(app.drawingDane,app.radiiDane, app.setupDane,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                else
                        MakeGif(app.drawingDane,app.radiiDane, app.setupDane,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                end
            else
                if app.MakegifCheckBox.Value == 0
                        Animateline(app.drawingDane,app.radiiDane, app.setupDane,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                else
                        MakeGifline(app.drawingDane,app.radiiDane, app.setupDane,app.OffsetEditField.Value, app.LatencymsSlider.Value);
                end
            end
        end

        % Value changed function: ConenctpointsCheckBox
        function ConenctpointsCheckBoxValueChanged(app, event)
            %valuecheckbox = app.ConenctpointsCheckBox.Value;
            
        end

        % Value changed function: ConnectpointsCheckBox
        function ConnectpointsCheckBoxValueChanged(app, event)
            %valuecheckbox2 = app.ConnectpointsCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ApkaTransformatkaUIFigure = uifigure('Visible', 'off');
            app.ApkaTransformatkaUIFigure.Name = 'Apka Transformatka';
            app.LoadimageButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.DrawspirallyButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.DrawspirallyButton.ButtonPushedFcn = createCallbackFcn(app, @DrawspirallyButtonPushed, true);
            app.DrawspirallyButton.Text = {'Draw'; 'spirally'};
            app.DrawverticallyButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.DrawverticallyButton.ButtonPushedFcn = createCallbackFcn(app, @DrawverticallyButtonPushed, true);
            app.DrawverticallyButton.Text = {'Draw'; 'vertically'};
            app.LoaddatapointsButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.LoaddatapointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatapointsButtonPushed, true);
            app.LoaddatapointsButton.Text = {'Load '; 'data points'};
            app.TransformmethodDropDownLabel = uilabel(app.ApkaTransformatkaUIFigure);
            app.TransformmethodDropDownLabel.Text = 'Transform method';
            app.TransformmethodDropDown = uidropdown(app.ApkaTransformatkaUIFigure);
            app.TransformmethodDropDown.Items = {'along the contour', 'vertically', 'spirally'};
            app.TransformmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformmethodDropDownValueChanged, true);
            app.TransformmethodDropDown.Value = 'along the contour';
            app.DrawalongthecontourButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.DrawalongthecontourButton.ButtonPushedFcn = createCallbackFcn(app, @DrawalongthecontourButtonPushed, true);
            app.DrawalongthecontourButton.Text = {'Draw'; 'along the contour'};
            app.LatencymsSliderLabel = uilabel(app.ApkaTransformatkaUIFigure);
            app.LatencymsSliderLabel.Text = {'Latency'; '(ms)'};
            app.LatencymsSlider = uislider(app.ApkaTransformatkaUIFigure);
            app.LatencymsSlider.Limits = [0 1000];
            app.LatencymsSlider.ValueChangedFcn = createCallbackFcn(app, @LatencymsSliderValueChanged, true);
            app.OffsetEditFieldLabel = uilabel(app.ApkaTransformatkaUIFigure);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.ApkaTransformatkaUIFigure, 'numeric');
            app.OffsetEditField.Limits = [0 1000];
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Value = 10;
            app.CalculateButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NajpierwwgrajzdjecieLabel = uilabel(app.ApkaTransformatkaUIFigure);
            app.NajpierwwgrajzdjecieLabel.Visible = 'off';
            app.NajpierwwgrajzdjecieLabel.Text = 'Najpierw wgraj zdjecie!';
            app.DrawButton = uibutton(app.ApkaTransformatkaUIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ConenctpointsCheckBox = uicheckbox(app.ApkaTransformatkaUIFigure);
            app.ConenctpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConenctpointsCheckBoxValueChanged, true);
            app.ConenctpointsCheckBox.Text = {'Conenct'; 'points'};
            app.ConnectpointsCheckBox = uicheckbox(app.ApkaTransformatkaUIFigure);
            app.ConnectpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConnectpointsCheckBoxValueChanged, true);
            app.ConnectpointsCheckBox.Text = {'Connect'; ' points'};
            app.MakegifCheckBox = uicheckbox(app.ApkaTransformatkaUIFigure);
            app.MakegifCheckBox.Text = 'Make gif';
            app.UIAxes = uiaxes(app.ApkaTransformatkaUIFigure);
            title(app.UIAxes, 'Loaded image will appear after calculations')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.ApkaTransformatkaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Apka_Transformatka

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ApkaTransformatkaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApkaTransformatkaUIFigure)
        end
    end
end